classdef updated_app < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout          matlab.ui.container.GridLayout
        Image               matlab.ui.control.Image
        StarttheGameButton  matlab.ui.control.Button
        directions          matlab.ui.control.TextArea
        ImpressiveLabel     matlab.ui.control.Label
        restartButton       matlab.ui.control.Button
        YouloseLabel        matlab.ui.control.Label
        row_5_column_4      matlab.ui.control.Label
        row_4_column_4      matlab.ui.control.Label
        row_3_column_4      matlab.ui.control.Label
        row_2_column_4      matlab.ui.control.Label
        row_1_column_4      matlab.ui.control.Label
        Image2              matlab.ui.control.Image
        row_5_column_1      matlab.ui.control.Label
        row_4_column_1      matlab.ui.control.Label
        row_3_column_1      matlab.ui.control.Label
        row_2_column_1      matlab.ui.control.Label
        row_1_column_1      matlab.ui.control.Label
        edit_field          matlab.ui.control.EditField
        submitButton        matlab.ui.control.Button
        row_5_column_5      matlab.ui.control.Label
        row_4_column_5      matlab.ui.control.Label
        row_3_column_5      matlab.ui.control.Label
        row_2_column_5      matlab.ui.control.Label
        row_1_column_5      matlab.ui.control.Label
        row_5_column_3      matlab.ui.control.Label
        row_4_column_3      matlab.ui.control.Label
        row_3_column_3      matlab.ui.control.Label
        row_2_column_3      matlab.ui.control.Label
        row_1_column_3      matlab.ui.control.Label
        row_5_column_2      matlab.ui.control.Label
        row_4_column_2      matlab.ui.control.Label
        row_3_column_2      matlab.ui.control.Label
        row_2_column_2      matlab.ui.control.Label
        row_1_column_2      matlab.ui.control.Label
        guess_the_word      matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: submitButton
        function submitButtonPushed(comp, event)
    %%% this function runs whenever the user presses the submit button%%%
    persistent pressCount  %% the persistent function retains memory of the amount of time the button was previously pressed
    if isempty(pressCount) %% if the button was not pressed, the press count is 0
        pressCount = 0;
    end
    
    pressCount = pressCount + 1; %% if the button was pressed, add one to the pressCount
    
    valueMatrix = upper(comp.edit_field.Value(1, :)); % creates matrix/vector that stores the 'guess' that user types in
    
    
    row = pressCount; %% creates variable, row, that is representative with the amount of times the submit button has been pressed 
    
    if row <= 5  % make sure the row number is not greater than 5 (user only has 5 attempts to guess the word)
        for col = 1:5  % creates variable col to represent the columns, and loops through columns 1 to 5 (accounting for amount of letters there are in the word)
            comp.(['row_' num2str(row) '_column_' num2str(col)]).Text = valueMatrix(col); %% sets the letter the user guessed to be input into a specific box in the grid configuration 
        end
    end

%%   notes:  make edit field clear after submit is pushed, 
%%           make enter key call submitButtonPushed function,
%%           make pop-up window with game rules,
%%           make list of words, pick from list randomly,
%%           add timer


WORDS_array = [];
    fid = fopen('WORDS.txt', 'r'); % Open file for reading
    while ~feof(fid) % Loop until end of file
        line = fgetl(fid); % Read one line at a time
        WORDS_array = [WORDS_array; line];
    end
    
   
    global rand_num;
    if row == 1
        rand_num = randi(3102);
    end

    correct_word = WORDS_array(rand_num, :);


    green_color = [0.55 0.85 0.55]; %% creates a green color
    yellow_color = [0.95 0.95 0.35]; %% creates a yellow color
    grey_color = [0.7 0.7 0.7]; %% creates a grey color
    %% creates a character vector of the word the user is trying to guess
    green_letters = [0 0 0 0 0]; %% initializes a zeros vector
    yellow_letters = [0 0 0 0 0]; %% initalizes a zeros vector

    for j = 1:5 %% goes through each letter of the word
        if upper(correct_word(j)) == upper(comp.edit_field.Value(j)) %% if the correct letter is equal to the guessed letter
            green_letters(j) = 1; %% changes value of green_letters vector to 1
        end
    end

    for j = 1:5 %% goes through each letter of the word
        if green_letters(j) == 1 %% if the value in the green_letters vector is equal to 1
            continue; %% continue beyond this for-loop
        end

        if ismember(upper(comp.edit_field.Value(j)), upper(correct_word(green_letters == 0))) %% if the letter from the users guess is in the correct word but not in the correct place
            yellow_letters(j) = 1; %% change the value of the yellow_letters vector to 1
        end 
    end 
    
    for j = 1:5 %% goes through each letter of the word
        if green_letters(j) == 1  %% if the value of the green_letters vector is equal to 1 
            set(comp.(['row_' num2str(row) '_column_' num2str(j)]),'BackgroundColor', green_color); %% set the specific box in the grid configuration to a backrgound color of green 
            continue; %% continue beyond this loop
        end

        if yellow_letters(j) == 1 %if the value of the yellow_letters vector is equal to 1 
            set(comp.(['row_' num2str(row) '_column_' num2str(j)]), 'BackgroundColor', yellow_color); %% set the specific box in the grid configuration to a backrgound color of yellow
            continue; %% continue beyond this loop
        end

        set(comp.(['row_' num2str(row) '_column_' num2str(j)]), 'BackgroundColor', grey_color); %% if it is not yellow or green set the specific box in the grid configuration to a backrgound color of grey 
    end
    
    comp.edit_field.Value = '';

    pause(1.0);

    if green_letters == 1
        comp.ImpressiveLabel.Visible = 'on';
        pressCount = 0;
    elseif row == 5 
        comp.YouloseLabel.Visible = 'on';
        comp.restartButton.Visible = 'on';
        pressCount = 0;
    end
        
        end

        % Button pushed function: restartButton
        function restartButtonPushed(comp, event)

        comp.YouloseLabel.Visible = 'off';
        comp.restartButton.Visible = 'off';
        white_color = [1 1 1];
        blank_grid = '';
        for i = 1:5 
            for j = 1:5 
                set(comp.(['row_' num2str(i) '_column_' num2str(j)]),'BackgroundColor', white_color);
                set(comp.(['row_' num2str(i) '_column_' num2str(j)]),'Text', blank_grid);
            end
        end
       
        end

        % Button pushed function: StarttheGameButton
        function StarttheGameButtonPushed(comp, event)
            comp.directions.Visible = 'off';
            comp.StarttheGameButton.Visible = 'off';
            comp.Image.Visible = 'off';
            comp.directions.Editable = 'off';

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 624 475];
            comp.GridLayout = uigridlayout(comp);
            comp.guess_the_word = uilabel(comp.GridLayout);
            comp.guess_the_word.Text = {'Guess the word and check your answer by pressing the submit button.'; 'lowercase letters only!'};
            comp.row_1_column_2 = uilabel(comp.GridLayout);
            comp.row_1_column_2.Text = '';
            comp.row_2_column_2 = uilabel(comp.GridLayout);
            comp.row_2_column_2.Text = '';
            comp.row_3_column_2 = uilabel(comp.GridLayout);
            comp.row_3_column_2.Text = '';
            comp.row_4_column_2 = uilabel(comp.GridLayout);
            comp.row_4_column_2.Text = '';
            comp.row_5_column_2 = uilabel(comp.GridLayout);
            comp.row_5_column_2.Text = '';
            comp.row_1_column_3 = uilabel(comp.GridLayout);
            comp.row_1_column_3.Text = '';
            comp.row_2_column_3 = uilabel(comp.GridLayout);
            comp.row_2_column_3.Text = '';
            comp.row_3_column_3 = uilabel(comp.GridLayout);
            comp.row_3_column_3.Text = '';
            comp.row_4_column_3 = uilabel(comp.GridLayout);
            comp.row_4_column_3.Text = '';
            comp.row_5_column_3 = uilabel(comp.GridLayout);
            comp.row_5_column_3.Text = '';
            comp.row_1_column_5 = uilabel(comp.GridLayout);
            comp.row_1_column_5.Text = '';
            comp.row_2_column_5 = uilabel(comp.GridLayout);
            comp.row_2_column_5.Text = '';
            comp.row_3_column_5 = uilabel(comp.GridLayout);
            comp.row_3_column_5.Text = '';
            comp.row_4_column_5 = uilabel(comp.GridLayout);
            comp.row_4_column_5.Text = '';
            comp.row_5_column_5 = uilabel(comp.GridLayout);
            comp.row_5_column_5.Text = '';
            comp.submitButton = uibutton(comp.GridLayout, 'push');
            comp.submitButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @submitButtonPushed, true);
            comp.submitButton.Text = 'submit';
            comp.edit_field = uieditfield(comp.GridLayout, 'text');
            comp.edit_field.CharacterLimits = [0 5];
            comp.edit_field.InputType = 'letters';
            comp.row_1_column_1 = uilabel(comp.GridLayout);
            comp.row_1_column_1.Text = '';
            comp.row_2_column_1 = uilabel(comp.GridLayout);
            comp.row_2_column_1.Text = '';
            comp.row_3_column_1 = uilabel(comp.GridLayout);
            comp.row_3_column_1.Text = '';
            comp.row_4_column_1 = uilabel(comp.GridLayout);
            comp.row_4_column_1.Text = '';
            comp.row_5_column_1 = uilabel(comp.GridLayout);
            comp.row_5_column_1.Text = '';
            comp.Image2 = uiimage(comp.GridLayout);
            comp.Image2.ImageSource = fullfile(pathToMLAPP, 'cracked_egg.png');
            comp.row_1_column_4 = uilabel(comp.GridLayout);
            comp.row_1_column_4.Text = '';
            comp.row_2_column_4 = uilabel(comp.GridLayout);
            comp.row_2_column_4.Text = '';
            comp.row_3_column_4 = uilabel(comp.GridLayout);
            comp.row_3_column_4.Text = '';
            comp.row_4_column_4 = uilabel(comp.GridLayout);
            comp.row_4_column_4.Text = '';
            comp.row_5_column_4 = uilabel(comp.GridLayout);
            comp.row_5_column_4.Text = '';
            comp.YouloseLabel = uilabel(comp.GridLayout);
            comp.YouloseLabel.Visible = 'off';
            comp.YouloseLabel.Text = '   You lose.';
            comp.restartButton = uibutton(comp.GridLayout, 'push');
            comp.restartButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @restartButtonPushed, true);
            comp.restartButton.Visible = 'off';
            comp.restartButton.Text = 'restart';
            comp.ImpressiveLabel = uilabel(comp.GridLayout);
            comp.ImpressiveLabel.Visible = 'off';
            comp.ImpressiveLabel.Text = 'Impressive.';
            comp.directions = uitextarea(comp.GridLayout);
            comp.directions.Value = {'  How to Play.'; ''; '  Guess the Wordle in 5 tries.'; ''; '    If the letter is in the correct position and in the correct word, the background of the letter will turn GREEN.'; ''; '    If the letter is in the correct word but not in the correct position, the background of the letter will turn YELLOW.'; ''; '    If the letter is not in the correct word, the background of the letter will turn GREY.'};
            comp.StarttheGameButton = uibutton(comp.GridLayout, 'push');
            comp.StarttheGameButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StarttheGameButtonPushed, true);
            comp.StarttheGameButton.Text = 'Start the Game!';
            comp.Image = uiimage(comp.GridLayout);
            comp.Image.ImageSource = fullfile(pathToMLAPP, 'cracked_egg.png');
        end
    end
end